classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeforsomemedicationLabel  matlab.ui.control.Label
        WelcomeLabel                matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        StartOrangeButton           matlab.ui.control.Button
        StartPurpleButton           matlab.ui.control.Button
        EStopButton                 matlab.ui.control.Button
        StartGreenButton            matlab.ui.control.Button
        CollisiondetectionCheckBox  matlab.ui.control.CheckBox
        OrangeSpinner               matlab.ui.control.Spinner
        OrangeSpinnerLabel          matlab.ui.control.Label
        PurpleSpinner               matlab.ui.control.Spinner
        PurpleSpinnerLabel          matlab.ui.control.Label
        GreenSpinner                matlab.ui.control.Spinner
        GreenSpinnerLabel           matlab.ui.control.Label
    end


    properties (Access = private)

    end

    methods (Access = private)

        function results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global begin
            begin = 0;
            global beginpurple
            beginpurple = 0;
            global beginorange
            beginorange = 0;


        end

        % Value changed function: GreenSpinner
        function GreenSpinnerValueChanged(app, event)
            value = app.GreenSpinner.Value;
            disp('GS');
            global begin;
            if begin == 1
                if value == 1
                    disp('1 Green');
                    LabAssignment2(1);

                end

                if value == 2
                    disp('2 Green');
                    LabAssignment2(2);

                end

                if value == 3
                    disp('3 Green');
                    LabAssignment2(3);

                end

            end
        end

        % Value changed function: PurpleSpinner
        function PurpleSpinnerValueChanged(app, event)
            value = app.PurpleSpinner.Value;
            disp('PS');
            global beginpurple
            if beginpurple == 1

                if value == 1
                    disp('1 Purple');
                    LabAssignment2(4);

                end

                if value == 2
                    disp('2 Purple');
                    LabAssignment2(5);

                end

                if value == 3
                    disp('3 Purple');
                    LabAssignment2(6);

                end
            end
        end

        % Value changed function: OrangeSpinner
        function OrangeSpinnerValueChanged(app, event)
            value = app.OrangeSpinner.Value;

            disp('OS');
            global beginorange
            if beginorange == 1
                if value == 1
                    disp('1 Orange');
                    LabAssignment2(7);

                end

                if value == 2
                    disp('2 Orange');
                    LabAssignment2(8);

                end

                if value == 3
                    disp('3 Orange');
                    LabAssignment2(8);

                end
            end

        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            disp('E-stop pressed');
            pause();

        end

        % Value changed function: CollisiondetectionCheckBox
        function CollisiondetectionCheckBoxValueChanged(app, event)
            value = app.CollisiondetectionCheckBox.Value;
            if value ==1
                CollisionDetection();
            end
        end

        % Button pushed function: StartGreenButton
        function GreenStartButtonPushed(app, event)
            global begin
            begin = 1;
            GreenSpinnerValueChanged(app);
        end

        % Button pushed function: StartPurpleButton
        function StartPurpleButtonPushed(app, event)
            global beginpurple
            beginpurple = 1;
            PurpleSpinnerValueChanged(app);
        end

        % Button pushed function: StartOrangeButton
        function StartOrangeButtonPushed(app, event)
            global beginorange
            beginorange = 1;
            OrangeSpinnerValueChanged(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreenSpinnerLabel = uilabel(app.UIFigure);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.UIFigure);
            app.GreenSpinner.Limits = [0 3];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenSpinnerValueChanged, true);
            app.PurpleSpinnerLabel = uilabel(app.UIFigure);
            app.PurpleSpinnerLabel.Text = 'Purple';
            app.PurpleSpinner = uispinner(app.UIFigure);
            app.PurpleSpinner.Limits = [0 3];
            app.PurpleSpinner.ValueChangedFcn = createCallbackFcn(app, @PurpleSpinnerValueChanged, true);
            app.OrangeSpinnerLabel = uilabel(app.UIFigure);
            app.OrangeSpinnerLabel.Text = 'Orange';
            app.OrangeSpinner = uispinner(app.UIFigure);
            app.OrangeSpinner.Limits = [0 3];
            app.OrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @OrangeSpinnerValueChanged, true);
            app.CollisiondetectionCheckBox = uicheckbox(app.UIFigure);
            app.CollisiondetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollisiondetectionCheckBoxValueChanged, true);
            app.CollisiondetectionCheckBox.Text = 'Collision detection';
            app.StartGreenButton = uibutton(app.UIFigure, 'push');
            app.StartGreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenStartButtonPushed, true);
            app.StartGreenButton.Text = 'Start Green';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Icon = 'ESTOP.png';
            app.EStopButton.Text = 'E-Stop';
            app.StartPurpleButton = uibutton(app.UIFigure, 'push');
            app.StartPurpleButton.ButtonPushedFcn = createCallbackFcn(app, @StartPurpleButtonPushed, true);
            app.StartPurpleButton.Text = 'Start Purple';
            app.StartOrangeButton = uibutton(app.UIFigure, 'push');
            app.StartOrangeButton.ButtonPushedFcn = createCallbackFcn(app, @StartOrangeButtonPushed, true);
            app.StartOrangeButton.Text = 'Start Orange';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.TimeforsomemedicationLabel = uilabel(app.UIFigure);
            app.TimeforsomemedicationLabel.Text = {'  Time for some medication'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end